classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        Gauge       matlab.ui.control.LinearGauge
        statusLamp  matlab.ui.control.Lamp
        Label       matlab.ui.control.Label
        Switch      matlab.ui.control.Switch
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         % Description
         % exmaple data  (data is not lable)
       dataX = [0,1,3,1,0,-1,-3,-1,0,1,3,1,0]
       plot1,value1
       testF = 438


    end
    
    methods (Access = private)
        function start(app)
            
            while app.value1
                
                app.plot1 = plot(app.UIAxes,app.dataX,"Color","b");
                
                note = app.frequencyToNote(app.testF);
                freq = app.noteToFreq(note);
                app.Label.Text = note;
                app.Gauge.Value= app.testF-freq;
                
                pause(2)
            end
            
        end
        function closeApp(app)
            delete(app.plot1);
            app.Gauge.Value=0;
            app.Label.Text = "None";
        end

        function note = frequencyToNote(~,frequency)
            % Define the standard tuning frequency (A4)
            A4 = 440;

            % Calculate the note index
            noteIndex = round(12 * log2(frequency / A4) + 49);

            % Define the mapping of note indices to note names
            noteNames = {'A', 'A#', 'B', 'C', 'C#', 'D', 'D#', 'E', 'F', 'F#', 'G', 'G#'};

            % Calculate the octave and note within the octave
            octave = floor((noteIndex - 1) / 12);
            noteWithinOctave = mod(noteIndex - 1, 12) + 1;
            if noteWithinOctave > 3
                octave = octave +1 ;
            end
            % Get the note name
            note = [noteNames{noteWithinOctave}, num2str(octave)];
        end

       function freq = noteToFreq(~,target_note)
           note_freq = {'A0', 28; 'A#0', 29; 'B0', 31; ...
             'C1', 33; 'C#1', 35; 'D1', 37; 'D#1', 39; 'E1', 41; 'F1', 44; 'F#1', 46; 'G1', 49; 'G#1', 52; 'A1', 55; 'A#1', 58; 'B1', 62; ...
             'C2', 65; 'C#2', 69; 'D2', 73; 'D#2', 78; 'E2', 82; 'F2', 87; 'F#2', 92; 'G2', 98; 'G#2', 104; 'A2', 110; 'A#2', 117; 'B2', 123; ...
             'C3', 131; 'C#3', 139; 'D3', 147; 'D#3', 156; 'E3', 165; 'F3', 175; 'F#3', 185; 'G3', 196; 'G#3', 208; 'A3', 220; 'A#3', 233; 'B3', 247; ...
             'C4', 262; 'C#4', 277; 'D4', 294; 'D#4', 311; 'E4', 330; 'F4', 349; 'F#4', 370; 'G4', 392; 'G#4', 415; 'A4', 440; 'A#4', 466; 'B4', 494; ...
             'C5', 523; 'C#5', 554; 'D5', 587; 'D#5', 622; 'E5', 659; 'F5', 698; 'F#5', 740; 'G5', 784; 'G#5', 831; 'A5', 880; 'A#5', 932; 'B5', 988; ...
             'C6', 1047; 'C#6', 1109; 'D6', 1175; 'D#6', 1245; 'E6', 1319; 'F6', 1397; 'F#6', 1480; 'G6', 1568; 'G#6', 1661; 'A6', 1760; 'A#6', 1865; 'B6', 1976; ...
             'C7', 2093; 'C#7', 2217; 'D7', 2349; 'D#7', 2489; 'E7', 2637; 'F7', 2794; 'F#7', 2960; 'G7', 3136; 'G#7', 3322; 'A7', 3520; 'A#7', 3729; 'B7', 3951; ...
             'C8', 4186; 'C#8', 4435; 'D8', 4699; 'D#8', 4978; 'E8', 5274; 'F8', 5588; 'F#8', 5920; 'G8', 6272; 'G#8', 6645; 'A8', 7040; 'A#8', 7459; 'B8', 7902};
           index = find(strcmp(note_freq(:, 1), target_note));
           freq = note_freq{index, 2};
        end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            app.value1 = app.Switch.Value;
            if app.value1 == true
                app.statusLamp.Color = "green";
                start(app);
            else
                closeApp(app);
                app.statusLamp.Color = "red";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'auto tone')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ItemsData = [false true];
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = false;
            app.Label = uilabel(app.UIFigure);
            app.statusLamp = uilamp(app.UIFigure);
            app.Gauge = uigauge(app.UIFigure, 'linear');
            app.Gauge.Limits = [-20 20];
            app.Gauge.MajorTicks = [-20 -15 -10 -5 0 5 10 15 20];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end